classdef Array_Control_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label_10                     matlab.ui.control.Label
        BioinspiredScienceTechnologyResearchLabel  matlab.ui.control.Label
        UBDVTLaboratoryLabel         matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        TabGroup                     matlab.ui.container.TabGroup
        ControlPageTab               matlab.ui.container.Tab
        TestControlLabel             matlab.ui.control.Label
        StartTestButton              matlab.ui.control.Button
        SampleRatekHzEditField       matlab.ui.control.NumericEditField
        SampleRatekHzEditFieldLabel  matlab.ui.control.Label
        TestDurationsEditField       matlab.ui.control.NumericEditField
        TestDurationsEditFieldLabel  matlab.ui.control.Label
        TestConfigurationLabel       matlab.ui.control.Label
        SystemInitializedLamp        matlab.ui.control.Lamp
        SystemInitializedLampLabel   matlab.ui.control.Label
        CollectingDataLampLabel      matlab.ui.control.Label
        CollectingDataLamp           matlab.ui.control.Lamp
        ArrayControlPannelLabel      matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        AcousticsConfigurationTab    matlab.ui.container.Tab
        TestControlLabel_2           matlab.ui.control.Label
        DataHandlerLabel             matlab.ui.control.Label
        DataFileNameLabel            matlab.ui.control.Label
        VisualizeDataButton          matlab.ui.control.Button
        OutputSoundDropDown          matlab.ui.control.DropDown
        OutputSoundDropDownLabel     matlab.ui.control.Label
        PlaySoundButton              matlab.ui.control.Button
        OutputSoundLabel             matlab.ui.control.Label
        ControltestButton            matlab.ui.control.Button
        SaveMicDataButton            matlab.ui.control.Button
        matEditFieldLabel            matlab.ui.control.Label
        matEditField                 matlab.ui.control.EditField
        AcousticsControlPannelLabel  matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        CameraConfigurationTab       matlab.ui.container.Tab
        CameraControlPannelLabel     matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
    end

    properties (Access = public)
        % --DAQ Devices
            NI_daq;             % daq device obj
            sample_rate;        % Sample rate of daq in Hz
            s1 = "Dev1";        % Slots 
            s2 = "PXI1Slot3";
            s3 = "PXI1slot4";

        % --Initialize Microphone channels
            % --Initialize Terminal 1
                mic_01; mic_02; mic_03; mic_04; mic_05; mic_06; mic_07; 
                mic_08;
                
            % --Initialize Terminal 2
                mic_09; mic_10; mic_11; mic_12; mic_13; mic_14; mic_15; 
                mic_16;
    
            % --Initialize Terminal 3
                mic_17; mic_18; mic_19; mic_20; mic_21; mic_22; mic_23; 
                mic_24;
    
            % --Initialize Terminal 4
                mic_25; mic_26; mic_27; mic_28; mic_29; mic_30; mic_31; 
                mic_32;

        % --Initialize Camera Output channels
            trigger_signal;
            trigger_channel;
            trigger_voltage;
            sync_signal;
            sync_channel;
            delay_time;
            
        % --Test control variables
            is_running;         % test is running bool
            time_start;         % Time obj for when test starts
            time_end;           % Time obj for when test ends
            test_duration;      % Test duration
            test_data;

        % --Save data
            data_title;
            mat_title;
            BG_test_data;
            test_data_matrix;

        % --Sound Output
            sound_value;        % Sound file drop-down selection index
            playSound;          % Play sound bool
            sound_sample;       % Sound sample rate
            audio;
                chirp_sound;
                chirp_fs;
                tone_sound;
                tone_fs;
                DTMF_sound;
                DTMF_fs;
                keys_sound;
                keys_fs;
                bat_1_sound;
                bat_1_fs;
    
        % --Calling helper app
            visualizeData; 
    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Methods %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    methods (Access = public)

        function playSound = play_sound(~, audio, fs, playSound)
            %{
                Play sound if selected or will end sound if selected and
                sound is already being played. This will be able to play
                any input sound.

                Sam Kramer
                June 9th, 2023
            %}
    
            if playSound

                % --Play sound if play_sound is tru
                    sound(audio,fs)

                % --Reset toggle button if done playing sound
                    playSound = "false";

            elseif ~playSound

                % --Play sound if not true
                    clear sound

            end
        end 

        function BG_noise_data = background_noise_test(app)
        %{
            This will take a quick 5 second test that will be used for determining
            the background noise within the room before a test is taken. Defaults
            to 250,000 Hz sample rate for the test.
        
            Sam Kramer
            June 12th, 2023
        
            See also add_daq_channels, and save_daq_data
        %}
        
        % --Function Body
            try
                % --Setup Daq and channels
                    app.NI_daq.Rate = 250000;
            
                % --Start Test
                    app.CollectingDataLamp.Color = [0 1 0];
                    bg_test_start = datetime('now','Format','dd-MMM-uuuu HH:mm:ss.SSSS');
                    
                    start(app.NI_daq,"Duration",seconds(5))
                        pause(5)
                    stop(app.NI_daq)
                    
                    bg_test_end = datetime('now','Format','dd-MMM-uuuu HH:mm:ss.SSSS');
                    app.CollectingDataLamp.Color = [1 0 0];
    
                % --Read data
                    bg_test_data = read(app.NI_daq,"all");
            
                % --Save Data as .csv file
                    BG_noise_data = save_daq_data(bg_test_data,bg_test_start,bg_test_end,'Background_Noise_Test');
    
            catch
    
            end
        end

        function reformat_data(app)
            %{
                This function will be used to reformat the data that is
                taken by the tests. This will take in the time table of the
                data and then will output a formatted matrix without the
                start and end time values.

                Sam Kramer
                June 26th, 2023
            %}

            % --Pull data out of timetable function
                time = app.test_data.Time;
                time = seconds(time);

                mic1 = app.test_data.Dev1_ai0;
                mic2 = app.test_data.Dev1_ai1;
                mic3 = app.test_data.Dev1_ai2;
                mic4 = app.test_data.Dev1_ai3;
                mic5 = app.test_data.Dev1_ai4;
                mic6 = app.test_data.Dev1_ai5;
                mic7 = app.test_data.Dev1_ai6;
                mic8 = app.test_data.Dev1_ai7;
                
                mic9 = app.test_data.PXI1Slot3_ai0;
                mic10 = app.test_data.PXI1Slot3_ai1;
                mic11 = app.test_data.PXI1Slot3_ai2;
                mic12 = app.test_data.PXI1Slot3_ai3;
                mic13 = app.test_data.PXI1Slot3_ai4;
                mic14 = app.test_data.PXI1Slot3_ai5;
                mic15 = app.test_data.PXI1Slot3_ai6;
                mic16 = app.test_data.PXI1Slot3_ai7;

                mic17 = app.test_data.PXI1Slot4_ai0;
                mic18 = app.test_data.PXI1Slot4_ai1;
                mic19 = app.test_data.PXI1Slot4_ai2;
                mic20 = app.test_data.PXI1Slot4_ai3;
                mic21 = app.test_data.PXI1Slot4_ai4;
                mic22 = app.test_data.PXI1Slot4_ai5;
                mic23 = app.test_data.PXI1Slot4_ai6;
                mic24 = app.test_data.PXI1Slot4_ai7;
                mic25 = app.test_data.PXI1Slot4_ai8;
                mic26 = app.test_data.PXI1Slot4_ai9;
                mic27 = app.test_data.PXI1Slot4_ai10;
                mic28 = app.test_data.PXI1Slot4_ai11;
                mic29 = app.test_data.PXI1Slot4_ai12;
                mic30 = app.test_data.PXI1Slot4_ai13;
                mic31 = app.test_data.PXI1Slot4_ai14;
                mic32 = app.test_data.PXI1Slot4_ai15;

            % --Place into a final matrix
                app.test_data_matrix = [time, mic1, mic2, mic3, mic4 , mic5, mic6, mic7, mic8, ...
                                        mic9, mic10, mic11, mic12, mic13, mic14, mic15, mic16, ...
                                        mic17, mic18, mic19, mic20, mic21, mic22, mic23, mic24, ...
                                        mic25, mic26, mic27, mic28, mic29, mic30, mic31, mic32];
        end

        function create_trig_signal(app)
        %{
            Creates the signal that is used to trigger the camera array.
            Only inputs the app and does not explicitly output any
            variables, will simply modify the app properties initialized
            above.

            Sam Kramer
            July 7th, 2023
        %}

        % --Create signal
            x = 1:app.sample_rate*app.test_duration;
            app.trigger_signal = app.trigger_voltage.*[heaviside(x)]';
            app.trigger_signal = [app.trigger_signal; zeros(5,1)];
            
        end

        function start_test(app)
            %{
                This function will be used to start tests that are used for
                collecting data. This function is used in both the
                background noise test function and the actual tests.

                Sam Kramer
                June 10th, 2023
            %}

            % --
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %{
                Startup function for the app. This handles all the startup
                and setup for the app. All mic channels opened in this
                function. The try statement is to prevent the app from
                crashing if there is no daq connected.
            
                Sam Kramer
                June 7th, 2023
            %}
            
            try     % Try connecting to daq

                % --Connecting to daq
                    app.NI_daq = daq("ni");
                    app.NI_daq.Rate = 10000;      % Set the daq sample rate
                    
                % --Adding channel inputs
                    % --Add inputs for terminal 1
                        app.mic_01 = addinput(app.NI_daq, app.s1,"ai0","Voltage");
                        app.mic_02 = addinput(app.NI_daq, app.s1,"ai1","Voltage");
                        app.mic_03 = addinput(app.NI_daq, app.s1,"ai2","Voltage");
                        app.mic_04 = addinput(app.NI_daq, app.s1,"ai3","Voltage");
                        app.mic_05 = addinput(app.NI_daq, app.s1,"ai4","Voltage");
                        app.mic_06 = addinput(app.NI_daq, app.s1,"ai5","Voltage");
                        app.mic_07 = addinput(app.NI_daq, app.s1,"ai6","Voltage");
                        app.mic_08 = addinput(app.NI_daq, app.s1,"ai7","Voltage");
    
                    % --Add inputs for terminal 2
                        app.mic_09 = addinput(app.NI_daq, app.s2,"ai0","Voltage");
                        app.mic_10 = addinput(app.NI_daq, app.s2,"ai1","Voltage");
                        app.mic_11 = addinput(app.NI_daq, app.s2,"ai2","Voltage");
                        app.mic_12 = addinput(app.NI_daq, app.s2,"ai3","Voltage");
                        app.mic_13 = addinput(app.NI_daq, app.s2,"ai4","Voltage");
                        app.mic_14 = addinput(app.NI_daq, app.s2,"ai5","Voltage");
                        app.mic_15 = addinput(app.NI_daq, app.s2,"ai6","Voltage");
                        app.mic_16 = addinput(app.NI_daq, app.s2,"ai7","Voltage");
    
                    % --Add inputs for terminal 3
                        app.mic_17 = addinput(app.NI_daq, app.s3,"ai0","Voltage");
                        app.mic_18 = addinput(app.NI_daq, app.s3,"ai1","Voltage");
                        app.mic_19 = addinput(app.NI_daq, app.s3,"ai2","Voltage");
                        app.mic_20 = addinput(app.NI_daq, app.s3,"ai3","Voltage");
                        app.mic_21 = addinput(app.NI_daq, app.s3,"ai4","Voltage");
                        app.mic_22 = addinput(app.NI_daq, app.s3,"ai5","Voltage");
                        app.mic_23 = addinput(app.NI_daq, app.s3,"ai6","Voltage");
                        app.mic_24 = addinput(app.NI_daq, app.s3,"ai7","Voltage");
    
                    % --Add inputs for terminal 4
                        app.mic_25 = addinput(app.NI_daq, app.s3,"ai8","Voltage");
                        app.mic_26 = addinput(app.NI_daq, app.s3,"ai9","Voltage");
                        app.mic_27 = addinput(app.NI_daq, app.s3,"ai10","Voltage");
                        app.mic_28 = addinput(app.NI_daq, app.s3,"ai11","Voltage");
                        app.mic_29 = addinput(app.NI_daq, app.s3,"ai12","Voltage");
                        app.mic_30 = addinput(app.NI_daq, app.s3,"ai13","Voltage");
                        app.mic_31 = addinput(app.NI_daq, app.s3,"ai14","Voltage");
                        app.mic_32 = addinput(app.NI_daq, app.s3,"ai15","Voltage");

                    % --Add Output channels for terminal 1
                        app.trigger_channel = addoutput(app.NI_daq, app.s1,"ai1","Voltage");

            catch       % if there is an error, return

            end

            % --Test setup
                app.is_running = false;
                app.test_duration = 2;
                app.CollectingDataLamp.Color = [1 0 0];

            % --Save data as .mat file
                date_test = datetime("now");
                dayday = string(day(date_test));
                monthmonth = string(month(date_test));
                yearyear = string(year(date_test));
                app.mat_title = "Test_Data_" + monthmonth + "_" + dayday + "_" + yearyear; 
                app.matEditField.Value = app.mat_title;
                app.mat_title = app.mat_title + ".mat";

            % --Sound Output
                app.sound_value = "Tone";
                app.playSound = false;

                [app.chirp_sound, app.chirp_fs] = audioread("Chirp 440 Hz.wav");
                [app.tone_sound, app.tone_fs] = audioread("Tone 440 Hz.mp3");
                [app.DTMF_sound, app.DTMF_fs] = audioread("DTMF Tones.wav");
                [app.keys_sound, app.keys_fs] = audioread("Shaking Keys.mp3");
                [app.bat_1_sound, app.bat_1_fs] = audioread("Nyctalus_leisleri_3_o.wav");

            % --Set basic audio output to chirp sound
                app.audio = app.chirp_sound;
                app.sound_sample = app.chirp_fs;

            % --Disable visualize data
                app.VisualizeDataButton.Enable = 'off';

            % --Indicate Start-up function is complete
                app.SystemInitializedLamp.Color = [0 1 0];
                
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %{
                Properly cancels all actions the app is doing and will
                close app. Called when user presses the "x" button to close
                app.

                Sam Kramer
                June 8th, 2023
            %}

            % --Close app
                delete(app)
        end

        % Value changed function: TestDurationsEditField
        function TestDurationsEditFieldValueChanged(app, event)
            %{
                Changes the duration of the test. Will be in seconds.
                
                Sam Kramer
                June 7th, 2023
            %}
            
                app.test_duration = app.TestDurationsEditField.Value;
                
        end

        % Value changed function: SampleRatekHzEditField
        function SampleRatekHzEditFieldValueChanged(app, event)
            %{
                User input of the sample rate for the DAQ obj, will
                automatically update the sample rate when field is changed.
                Defaults to 500 kHz;

                Sam Kramer
                June 6th, 2023
            %}
            
                sample_value = app.SampleRatekHzEditField.Value;
                app.sample_rate = sample_value * 1000;      % Converts to Hz
                app.NI_daq.Rate = app.sample_rate;      % Update sample rate
            
        end

        % Button pushed function: StartTestButton
        function StartTestButtonPushed(app, event)
            %{
                Starts the data collection for the system. When button is
                pushed test starts. There is no stop test button; test will
                end when duration runs out. Will save data automatically,
                data acquisition does not need to be enabled. User saves
                file as a .csv by clicking save data button.

                Sam Kramer
                June 8th, 2023
            %}

            %   [THIS NEEDS TO BE UPDATED TO RUN THE CAMERAS TOO]

                % --Setup test parameters
                    create_trig_signal(app);
  
                % --Start test if daq connected
                    app.CollectingDataLamp.Color = [0 1 0];
                    app.time_start = datetime('now','Format','dd-MMM-uuuu HH:mm:ss.SSSS');
    
                    try % to start test

                        start(app.NI_daq,"Duration",app.test_duration)
                            pause(app.test_duration)
                        stop(app.NI_daq)

                    catch % if no daq connected
                        
                    end
    
                % --End Test
                    app.time_end = datetime('now','Format','dd-MMM-uuuu HH:mm:ss.SSSS');
    
                % --Read Data if daq is connected if not make fake data
                    try app.test_data = read(app.NI_daq,"all");
                    catch
                        zero_vec = ones([2000, 32]);
                        time_append = (0:(1/2000):1 - (1/2000))';
                        app.test_data = [time_append, zero_vec];
                    end

                % --Assign to workspace
                    assignin("base","test_data",app.test_data);
                    app.CollectingDataLamp.Color = [1 0 0];

                % --Reformat data and convert to matrix
                    try
                        reformat_data(app);
                    catch
                        app.test_data_matrix = app.test_data;
                    end
                    
                % --Enable visualize data button
                    app.VisualizeDataButton.Enable = 'on';

        end

        % Value changed function: matEditField
        function matEditFieldValueChanged(app, event)
           %{
                Changes the save name of the csv file that will be created
                when saving is clicked. app.csv_title is a string function.

                Sam Kramer
                June 8th, 2023
            %}
            
            % --Change file name
                app.data_title = string(app.matEditField.Value);
                app.mat_title = app.data_title + ".mat";
            
        end

        % Button pushed function: SaveMicDataButton
        function SaveMicDataButtonPushed(app, event)
            %{
                Saves data from tests into a .csv file

                Sam Kramer
                June 7th, 2023
            %}

            % --Add start time
                day_test_start = day(app.time_start);
                hour_test_start = hour(app.time_start);
                min_test_start = minute(app.time_start);
                second_test_start = second(app.time_start);

                time_start_vector = [day_test_start, hour_test_start, min_test_start, second_test_start];

                zero_append = zeros([1, 33 - length(time_start_vector)]);
                
                time_start_vector = [time_start_vector, zero_append];

            % --Add end time
                day_test_end = day(app.time_end);
                hour_test_end = hour(app.time_end);
                min_test_end = minute(app.time_end);
                second_test_end = second(app.time_end);

                time_end_vector = [day_test_end, hour_test_end, min_test_end, second_test_end];

                time_end_vector = [time_end_vector, zero_append];

            % --Compile as one matrix
                final_output_data = [time_start_vector; app.test_data_matrix; time_end_vector];

            % --Save into .mat
                save(app.mat_title, "final_output_data")
                
        end

        % Button pushed function: ControltestButton
        function ControltestButtonPushed(app, event)
            %{
                Will Toggle a 5 second background noise test that can be
                used as a test base-line control sample.

                Sam Kramer
                June 14th, 2023
            %}

            % --Call background test
                try
                    % --Take background test
                        app.BG_test_data = background_noise_test(app);

                     % --Enable mic check button
                        app.VisualizeDataButton.Enable = 'on';
                catch
                    % --Cancel test
                        app.CollectingDataLamp.Color = [1 0 0];

                    % --Disable mic check button
                        app.VisualizeDataButton.Enable = 'off';
                end

        end

        % Value changed function: OutputSoundDropDown
        function OutputSoundDropDownValueChanged(app, event)
            %{
                Change the sound file that will be selected to play by the
                system.

                Sam Kramer
                June 9th, 2023
            %}
            
            % --Pull out sound from drop-down menu
                app.sound_value = app.OutputSoundDropDown.Value;

            % --If Statements to set app.audio
                if app.sound_value == "Chirp"
    
                    app.audio = app.chirp_sound;
                    app.sound_sample = app.chirp_fs;
    
                elseif app.sound_value == "Tone"
    
                    app.audio = app.tone_sound;
                    app.sound_sample = app.tone_fs;
    
                elseif app.sound_value == "Multi-Tone"
    
                    app.audio = app.DTMF_sound;
                    app.sound_sample = app.DTMF_fs;
    
                elseif app.sound_value == "Key Shake"
                    
                    app.audio = app.keys_sound;
                    app.sound_sample = app.keys_fs;
    
                elseif app.sound_value == "Bat Sound"
    
                    app.audio = app.bat_1_sound;
                    app.sound_sample = app.bat_1_fs;
    
                else    % Catch-all statement
                    
                    app.audio = app.tone_sound;
                    app.sound_sample = app.tone_fs;
    
                end
            
        end

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            %{
                Will toggle sound output if button is pushed by user
                
                Sam Kramer
                June 9th, 2023
            %}
    
            % --Toggle the play_sound function
                app.playSound = ~app.playSound;
                app.playSound = play_sound(app, app.audio, app.sound_sample, app.playSound);
                
        end

        % Button pushed function: VisualizeDataButton
        function VisualizeDataButtonPushed(app, event)
            %{
                Will toggle the mic check function command that will be
                used to run a baseline test for the mics before the data is
                acquired. Acts as a control, and allows user to see what
                mics are not working and what mics are working.

                Sam Kramer
                June 14th, 2023
            %}
            
            % --Disable the button when the other app is open
                app.VisualizeDataButton.Enable = 'off';

            % --Call Mic_Check_App class
                if ~isempty(app.BG_test_data)
                    Mic_Check_App(app, app.BG_test_data);
                else
                    Mic_Check_App(app, app.test_data_matrix);
                end

            % --Reable button after other app is close
                app.VisualizeDataButton.Enable = 'on';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlPageTab = uitab(app.TabGroup);
            app.ControlPageTab.Title = 'Control Page';
            app.Label_7 = uilabel(app.ControlPageTab);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.ControlPageTab);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.ControlPageTab);
            app.Label_9.Text = '';
            app.ArrayControlPannelLabel = uilabel(app.ControlPageTab);
            app.ArrayControlPannelLabel.Text = 'Array Control Pannel';
            app.CollectingDataLamp = uilamp(app.ControlPageTab);
            app.CollectingDataLamp.Tag = 'lamp';
            app.CollectingDataLampLabel = uilabel(app.ControlPageTab);
            app.CollectingDataLampLabel.Text = 'Collecting Data';
            app.SystemInitializedLampLabel = uilabel(app.ControlPageTab);
            app.SystemInitializedLampLabel.Text = 'System Initialized';
            app.SystemInitializedLamp = uilamp(app.ControlPageTab);
            app.TestConfigurationLabel = uilabel(app.ControlPageTab);
            app.TestConfigurationLabel.Text = 'Test Configuration';
            app.TestDurationsEditFieldLabel = uilabel(app.ControlPageTab);
            app.TestDurationsEditFieldLabel.Text = 'Test Duration (s)';
            app.TestDurationsEditField = uieditfield(app.ControlPageTab, 'numeric');
            app.TestDurationsEditField.ValueChangedFcn = createCallbackFcn(app, @TestDurationsEditFieldValueChanged, true);
            app.TestDurationsEditField.Value = 2;
            app.SampleRatekHzEditFieldLabel = uilabel(app.ControlPageTab);
            app.SampleRatekHzEditFieldLabel.Text = 'Sample Rate (kHz)';
            app.SampleRatekHzEditField = uieditfield(app.ControlPageTab, 'numeric');
            app.SampleRatekHzEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRatekHzEditFieldValueChanged, true);
            app.SampleRatekHzEditField.Value = 10;
            app.StartTestButton = uibutton(app.ControlPageTab, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestButtonPushed, true);
            app.StartTestButton.Text = 'Start Test';
            app.TestControlLabel = uilabel(app.ControlPageTab);
            app.TestControlLabel.Text = 'Test Control';
            app.AcousticsConfigurationTab = uitab(app.TabGroup);
            app.AcousticsConfigurationTab.Title = 'Acoustics Configuration';
            app.Label_4 = uilabel(app.AcousticsConfigurationTab);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.AcousticsConfigurationTab);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.AcousticsConfigurationTab);
            app.Label_6.Text = '';
            app.AcousticsControlPannelLabel = uilabel(app.AcousticsConfigurationTab);
            app.AcousticsControlPannelLabel.Text = 'Acoustics Control Pannel';
            app.matEditField = uieditfield(app.AcousticsConfigurationTab, 'text');
            app.matEditField.ValueChangedFcn = createCallbackFcn(app, @matEditFieldValueChanged, true);
            app.matEditField.Value = 'test_data';
            app.matEditFieldLabel = uilabel(app.AcousticsConfigurationTab);
            app.matEditFieldLabel.Text = '.mat';
            app.SaveMicDataButton = uibutton(app.AcousticsConfigurationTab, 'push');
            app.SaveMicDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMicDataButtonPushed, true);
            app.SaveMicDataButton.Text = 'Save Mic Data';
            app.ControltestButton = uibutton(app.AcousticsConfigurationTab, 'push');
            app.ControltestButton.ButtonPushedFcn = createCallbackFcn(app, @ControltestButtonPushed, true);
            app.ControltestButton.Text = 'Control test';
            app.OutputSoundLabel = uilabel(app.AcousticsConfigurationTab);
            app.OutputSoundLabel.Text = 'Output Sound';
            app.PlaySoundButton = uibutton(app.AcousticsConfigurationTab, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound';
            app.OutputSoundDropDownLabel = uilabel(app.AcousticsConfigurationTab);
            app.OutputSoundDropDownLabel.Text = 'Output Sound';
            app.OutputSoundDropDown = uidropdown(app.AcousticsConfigurationTab);
            app.OutputSoundDropDown.Items = {'-- Select Sound --', 'Tone', 'Chirp', 'Multi-Tone', 'Key Shake', 'Bat Sound'};
            app.OutputSoundDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputSoundDropDownValueChanged, true);
            app.OutputSoundDropDown.Value = '-- Select Sound --';
            app.VisualizeDataButton = uibutton(app.AcousticsConfigurationTab, 'push');
            app.VisualizeDataButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeDataButtonPushed, true);
            app.VisualizeDataButton.Text = 'Visualize Data';
            app.DataFileNameLabel = uilabel(app.AcousticsConfigurationTab);
            app.DataFileNameLabel.Text = 'Data File Name:';
            app.DataHandlerLabel = uilabel(app.AcousticsConfigurationTab);
            app.DataHandlerLabel.Text = 'Data Handler';
            app.TestControlLabel_2 = uilabel(app.AcousticsConfigurationTab);
            app.TestControlLabel_2.Text = 'Test Control';
            app.CameraConfigurationTab = uitab(app.TabGroup);
            app.CameraConfigurationTab.Title = 'Camera Configuration';
            app.Label = uilabel(app.CameraConfigurationTab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.CameraConfigurationTab);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.CameraConfigurationTab);
            app.Label_3.Text = '';
            app.CameraControlPannelLabel = uilabel(app.CameraConfigurationTab);
            app.CameraControlPannelLabel.Text = 'Camera Control Pannel';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Vertical_VT_Full_Color_RGB.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'UBD logo original.png');
            app.UBDVTLaboratoryLabel = uilabel(app.UIFigure);
            app.UBDVTLaboratoryLabel.Text = 'UBD - VT Laboratory';
            app.BioinspiredScienceTechnologyResearchLabel = uilabel(app.UIFigure);
            app.BioinspiredScienceTechnologyResearchLabel.Text = 'Bioinspired Science & Technology Research';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '2023';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Array_Control_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end