classdef Mic_Check_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        NextDataSetButton   matlab.ui.control.Button
        DataCollectedLabel  matlab.ui.control.Label
        UIAxes_3            matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    properties (Access = public)
        %{
            Properties for this helper app. This is used for the mic_check
            functionality in the control pannel app.

            Sam Kramer
            June 16th, 2023
        %}

        % --Initialize main app variable
            main_app;
    
        % --Initialize data for plotting
            test_data;

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, raw_data)
            %{
                The start up function for the helper mic_check app for
                visualizing the data to the user.

                Sam Kramer
                June 16th, 2023
            %}

            % --Storing main app in this app as app.main_app
                app.main_app = caller;

            % --Storing raw data
                app.test_data = raw_data;

            % --Plot data on the graphs
                

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %{
                Properly closes the app when the user is done viewing the
                data.

                Sam Kramer
                June 16th, 2023
            %}
           
            % --Delete app
                delete(app)
        end

        % Button pushed function: NextDataSetButton
        function NextDataSetButtonPushed(app, event)
            %{
                This is the function that allows the user to click through
                all the data sets to see what microphones are not working
                and exclude the mics that do not work. This will simply
                tick through an integer and call an updator function.

                Sam Kramer
                June 16, 2023
            %}
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.DataCollectedLabel = uilabel(app.UIFigure);
            app.DataCollectedLabel.Text = 'Data Collected';
            app.NextDataSetButton = uibutton(app.UIFigure, 'push');
            app.NextDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @NextDataSetButtonPushed, true);
            app.NextDataSetButton.Text = 'Next Data Set';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mic_Check_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end